classdef AppointmentPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BookingTypeDropDown            matlab.ui.control.DropDown
        BookingTypeDropDownLabel       matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        SubmitButton                   matlab.ui.control.Button
        ChiefComplaintEditFieldLabel   matlab.ui.control.Label
        ChiefComplaintEditField        matlab.ui.control.EditField
        PatientNameEditField           matlab.ui.control.EditField
        ChooseTimeDropDownLabel_3      matlab.ui.control.Label
        SelectaPatientDropDown         matlab.ui.control.DropDown
        ChooseTimeDropDownLabel_2      matlab.ui.control.Label
        ChooseTimeDropDown             matlab.ui.control.DropDown
        ChooseTimeDropDownLabel        matlab.ui.control.Label
        ChoosedateofConsultationDatePicker  matlab.ui.control.DatePicker
        ChoosedateofConsultationDatePickerLabel  matlab.ui.control.Label
        AvailabilityLabel              matlab.ui.control.Label
        AddressLabel                   matlab.ui.control.Label
        ProfessionSpecializationLabel  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        DoctorLabel                    matlab.ui.control.Label
        LBYEC2BMedicalCenterLabel      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end


     properties (Access = public)
        CurrentUser
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, docName, docSpec, docRoom, docDays, docImg)
            arguments
                app
                docName = "Dr.Name here";
                docSpec = "Profession - Specialization"
                docRoom = "Room number here"
                docDays = "Dates Available here"
                docImg = "default.png"
            end

            app.Image_2.ImageSource = docImg;
            app.DoctorLabel.Text = docName;
            app.ProfessionSpecializationLabel.Text = docSpec;
            app.AddressLabel.Text = docRoom;
            app.AvailabilityLabel.Text = docDays;
            
            app.ChoosedateofConsultationDatePicker.Limits = [datetime('today') datetime('today') + calyears(1)];

    
            switch docName
                case 'Dr. Jose Rizal'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [1 3 5 7];
                case 'Dr. John Doe'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [1 2 4 6 7];
                case 'Dr. Maria Luna'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = 1;
                case 'Dr. Daniel Cruz'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [1 3 4 5 7];
                case 'Dr. Elena Santos'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [1 2 3 5 6 7];
                case 'Dr. Miguel Ramos' 
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [1 2 4 6 7];
                case 'Dr. Carla Reyes'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [1 5 6 7];
                case 'Dr. Alvin Uy'
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [];
                otherwise
                    app.ChoosedateofConsultationDatePicker.DisabledDaysOfWeek = [];
            end

             if isfile('currentUser.mat') && isfile('userAccounts.mat')
                load('currentUser.mat', 'idx');
                load('userAccounts.mat', 'users');
                if idx > 0 && idx <= length(users)
                    app.CurrentUser = users(idx);
                    disp(['Logged in as: ', app.CurrentUser.email])
                else
                    uialert(app.UIFigure, 'Invalid current user index.', 'Error');
                end
            else
                uialert(app.UIFigure, 'No logged-in user found. Please log in first.', 'Error');
            end
            app.SelectaPatientDropDownValueChanged();
           

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            DashboardPage;
            delete(app)
            
        end

        % Value changed function: SelectaPatientDropDown
        function SelectaPatientDropDownValueChanged(app, event)
        value = app.SelectaPatientDropDown.Value;

        switch value
            case 'Myself'        
                if ~isempty(app.CurrentUser)
                    app.PatientNameEditField.Value = app.CurrentUser.name;
                end
                app.PatientNameEditField.Enable = "off";
            case 'Someone else'
                app.PatientNameEditField.Value = "";
                app.PatientNameEditField.Placeholder = "Name of patient";
                app.PatientNameEditField.Enable = "on";
        end
             
          
        end

        % Value changed function: PatientNameEditField
        function PatientNameEditFieldValueChanged(app, event)
            value = app.PatientNameEditField.Value;            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            docName = app.DoctorLabel.Text;
            docRoom = app.AddressLabel.Text;
            docDate = app.ChoosedateofConsultationDatePicker.Value;
            docTime = app.ChooseTimeDropDown.Value;
            patientName = app.PatientNameEditField.Value;
            patientComplaint = app.ChiefComplaintEditField.Value;
            patientBookingType = app.BookingTypeDropDown.Value;

            if isempty(patientName) || isnat(docDate) || isempty(docTime) || isempty(patientComplaint)
                uialert(app.UIFigure, 'Please fill out all required fields before continuing.', 'Missing Information');
                return;
            end

            if docDate < datetime('today')
                uialert(app.UIFigure, 'Please pick a date past today.', 'Invalid Date')
                return;
            end

            ConfirmationPage(docName, docRoom, docDate, docTime, patientName, patientComplaint, patientBookingType)
            delete(app)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.LBYEC2BMedicalCenterLabel = uilabel(app.UIFigure);
            app.LBYEC2BMedicalCenterLabel.Text = 'LBYEC2B Medical Center';
            app.DoctorLabel = uilabel(app.UIFigure);
            app.DoctorLabel.Text = 'DR. Name here';
            app.Image_2 = uiimage(app.UIFigure);
            app.ProfessionSpecializationLabel = uilabel(app.UIFigure);
            app.ProfessionSpecializationLabel.Text = 'Profession - Specialization';
            app.AddressLabel = uilabel(app.UIFigure);
            app.AddressLabel.Text = 'Room number here';
            app.AvailabilityLabel = uilabel(app.UIFigure);
            app.AvailabilityLabel.Text = 'Dates Available here';
            app.ChoosedateofConsultationDatePickerLabel = uilabel(app.UIFigure);
            app.ChoosedateofConsultationDatePickerLabel.Text = 'Choose date of Consultation';
            app.ChoosedateofConsultationDatePicker = uidatepicker(app.UIFigure);
            app.ChooseTimeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseTimeDropDownLabel.Text = 'Choose Time';
            app.ChooseTimeDropDown = uidropdown(app.UIFigure);
            app.ChooseTimeDropDown.Items = {'7:00 AM', '8:00 AM', '9:00 AM', '10:00 AM', '11:00 AM', '1:00 PM', '2:00 PM', '3:00 PM', '4:00 PM', '5:00 PM', '6:00 PM', '7:00 PM'};
            app.ChooseTimeDropDown.Value = '7:00 AM';
            app.ChooseTimeDropDownLabel_2 = uilabel(app.UIFigure);
            app.ChooseTimeDropDownLabel_2.Text = 'Select a Patient';
            app.SelectaPatientDropDown = uidropdown(app.UIFigure);
            app.SelectaPatientDropDown.Items = {'Myself', 'Someone else'};
            app.SelectaPatientDropDown.Editable = 'on';
            app.SelectaPatientDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaPatientDropDownValueChanged, true);
            app.SelectaPatientDropDown.Placeholder = 'Select...';
            app.SelectaPatientDropDown.Value = 'Myself';
            app.ChooseTimeDropDownLabel_3 = uilabel(app.UIFigure);
            app.ChooseTimeDropDownLabel_3.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.UIFigure, 'text');
            app.PatientNameEditField.ValueChangedFcn = createCallbackFcn(app, @PatientNameEditFieldValueChanged, true);
            app.PatientNameEditField.Placeholder = 'Name of patient';
            app.ChiefComplaintEditField = uieditfield(app.UIFigure, 'text');
            app.ChiefComplaintEditField.Placeholder = 'Briefly describe the main concern.';
            app.ChiefComplaintEditFieldLabel = uilabel(app.UIFigure);
            app.ChiefComplaintEditFieldLabel.Text = 'Chief Complaint';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.BookingTypeDropDownLabel = uilabel(app.UIFigure);
            app.BookingTypeDropDownLabel.Text = 'Booking Type';
            app.BookingTypeDropDown = uidropdown(app.UIFigure);
            app.BookingTypeDropDown.Items = {'PRE PAID', 'POST PAID'};
            app.BookingTypeDropDown.Value = 'PRE PAID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppointmentPage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end