classdef ConfirmationPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ConsultDate                  matlab.ui.control.DatePicker
        DateofConsultationDatePickerLabel  matlab.ui.control.Label
        ConfirmYourAppointmentLabel  matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
        ConfirmButton                matlab.ui.control.Button
        EditField_8                  matlab.ui.control.EditField
        NameofDoctorLabel_8          matlab.ui.control.Label
        EditField_7                  matlab.ui.control.EditField
        NameofDoctorLabel_7          matlab.ui.control.Label
        EditField_6                  matlab.ui.control.EditField
        NameofDoctorLabel_6          matlab.ui.control.Label
        EditField_5                  matlab.ui.control.EditField
        NameofDoctorLabel_5          matlab.ui.control.Label
        EditField_4                  matlab.ui.control.EditField
        NameofDoctorLabel_4          matlab.ui.control.Label
        EditField_2                  matlab.ui.control.EditField
        NameofDoctorLabel_2          matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        NameofDoctorLabel            matlab.ui.control.Label
    end

    
     properties (Access = public)
        currentUser
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, docName, docRoom, docDate, docTime, patientName, patientComplaint, patientBookingType)
            
            arguments
                app
                docName = "Name"
                docRoom = "Room"
                docDate = "N/A"
                docTime = "N/A"
                patientName = "N/A"
                patientComplaint = ""
                patientBookingType = ""
            end

            if isfile('currentUser.mat') && isfile('userAccounts.mat')
                load('currentUser.mat', 'idx');
                load('userAccounts.mat', 'users');
                if idx > 0 && idx <= length(users)
                    app.currentUser = users(idx);
                    disp(['Logged in as: ', app.currentUser.email])
                else
                    uialert(app.UIFigure, 'Invalid current user index.', 'Error');
                end
            else
                uialert(app.UIFigure, 'No logged-in user found. Please log in first.', 'Error');
            end
           
            
            app.EditField.Value = docName;
            app.EditField_2.Value = docRoom;
            if ~isa(docDate, 'datetime')
                docDate = datetime(docDate);  % Convert if it came as text
            end
            app.ConsultDate.Value = docDate;
            app.EditField_4.Value = docTime;
            app.EditField_5.Value = patientName;
            app.EditField_6.Value = patientComplaint;
            app.EditField_7.Value = patientBookingType;


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            DashboardPage;
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
          
            fileName = 'appointmentsData.mat';
            if isfile(fileName)
                load(fileName, 'appointmentsData');
            else
                appointmentsData = struct('Doctor', {}, 'Room', {}, 'Date', {}, ...
                                       'Time', {}, 'Patient', {}, 'Complaint', {}, 'Type', {}, "Creator", {});
            end

            newAppt.Doctor    = app.EditField.Value;
            newAppt.Room      = app.EditField_2.Value;
            newAppt.Date      = app.ConsultDate.Value;
            newAppt.Time      = app.EditField_4.Value;
            newAppt.Patient   = app.EditField_5.Value;
            newAppt.Complaint = app.EditField_6.Value;
            newAppt.Type      = app.EditField_7.Value;
            newAppt.Creator   = app.currentUser.name;
            hasConflict = false;
            for i = 1:numel(appointmentsData)
                sameDoctor = strcmp(appointmentsData(i).Doctor, newAppt.Doctor);
                sameDate   = isequal(appointmentsData(i).Date, newAppt.Date);
                sameTime   = strcmp(appointmentsData(i).Time, newAppt.Time);
        
                if sameDoctor && sameDate && sameTime
                    hasConflict = true;
                    break;
                end
            end            
           
            if hasConflict
                uialert(app.UIFigure, 'This appointment conflicts with an existing booking.', 'Conflict');
             else
            
            appointmentsData(end+1) = newAppt; 
            save(fileName, 'appointmentsData')
            uialert(app.UIFigure, 'Appointment confirmed successfully!', 'Success');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NameofDoctorLabel = uilabel(app.UIFigure);
            app.NameofDoctorLabel.Text = 'Name of Doctor';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Enable = 'off';
            app.NameofDoctorLabel_2 = uilabel(app.UIFigure);
            app.NameofDoctorLabel_2.Text = 'Clinic';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Enable = 'off';
            app.NameofDoctorLabel_4 = uilabel(app.UIFigure);
            app.NameofDoctorLabel_4.Text = 'Time of Consultation';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Enable = 'off';
            app.NameofDoctorLabel_5 = uilabel(app.UIFigure);
            app.NameofDoctorLabel_5.Text = 'Name of Patient';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Enable = 'off';
            app.NameofDoctorLabel_6 = uilabel(app.UIFigure);
            app.NameofDoctorLabel_6.Text = 'Chief Complaint';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Enable = 'off';
            app.NameofDoctorLabel_7 = uilabel(app.UIFigure);
            app.NameofDoctorLabel_7.Text = 'Booking Type';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Enable = 'off';
            app.NameofDoctorLabel_8 = uilabel(app.UIFigure);
            app.NameofDoctorLabel_8.Text = 'Total Amount Due';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.Enable = 'off';
            app.EditField_8.Value = 'PHP 4000';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ConfirmYourAppointmentLabel = uilabel(app.UIFigure);
            app.ConfirmYourAppointmentLabel.Text = 'Confirm Your Appointment';
            app.DateofConsultationDatePickerLabel = uilabel(app.UIFigure);
            app.DateofConsultationDatePickerLabel.Text = 'Date of Consultation';
            app.ConsultDate = uidatepicker(app.UIFigure);
            app.ConsultDate.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfirmationPage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end