classdef CreateAccountPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BacktoSignInButton         matlab.ui.control.Button
        CreateAccountButton        matlab.ui.control.Button
        IagreetothetermsandconditionsofthisprogramCheckBox  matlab.ui.control.CheckBox
        ConfirmPasswordField       matlab.ui.control.EditField
        EmailLabel_10              matlab.ui.control.Label
        PasswordField              matlab.ui.control.EditField
        EmailLabel_9               matlab.ui.control.Label
        EmailLabel_8               matlab.ui.control.Label
        EmailField                 matlab.ui.control.EditField
        LoginInformationLabel      matlab.ui.control.Label
        EmailLabel_7               matlab.ui.control.Label
        EmailLabel_6               matlab.ui.control.Label
        ContactField               matlab.ui.control.NumericEditField
        GenderField                matlab.ui.control.DropDown
        EmailLabel_5               matlab.ui.control.Label
        BirthdayField              matlab.ui.control.DatePicker
        EmailLabel_4               matlab.ui.control.Label
        AgeField                   matlab.ui.control.NumericEditField
        AddressField               matlab.ui.control.EditField
        EmailLabel_3               matlab.ui.control.Label
        EmailLabel_2               matlab.ui.control.Label
        NameField                  matlab.ui.control.EditField
        PersonalInformationLabel   matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        LBYEC2BMedicalCenterLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
            if ~strcmp(app.PasswordField.Value, app.ConfirmPasswordField.Value)
                uialert(app.UIFigure, 'Passwords do not match.', 'Error');
                return;
            

            elseif isempty(app.NameField.Value) || isempty(app.EmailField.Value) || ...
                isempty(app.PasswordField.Value) || isempty(app.ConfirmPasswordField.Value)
                uialert(app.UIFigure, 'Please fill in all required fields.', 'Error');
                return;
            

            elseif ~app.IagreetothetermsandconditionsofthisprogramCheckBox.Value
                uialert(app.UIFigure, 'You must agree to the terms and conditions.', 'Error');
                return;
            end
            
            newUser.email = app.EmailField.Value;
            newUser.password = app.PasswordField.Value;
            newUser.name = app.NameField.Value;
            newUser.age = app.AgeField.Value;
            newUser.gender = app.GenderField.Value;
            newUser.birthday = app.BirthdayField.Value;
            newUser.address = app.AddressField.Value;
            newUser.contact = app.ContactField.Value;
     
            app.saveNewUser(newUser); % Call your helper method here

            uialert(app.UIFigure, 'Account created successfully!', 'Success');
        end
        function saveNewUser(~, newUser)
            if isfile('userAccounts.mat')
                load('userAccounts.mat', 'users');
            else
                users = [];
            end
            users = [users; newUser];
            save('userAccounts.mat', 'users');
            end

        function result = checkPasswordsMatch(~, pass1, pass2)
            result = strcmp(pass1, pass2);
        end

        % Button pushed function: BacktoSignInButton
        function BacktoSignInButtonPushed(app, event)
            LoginPage;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LBYEC2BMedicalCenterLabel = uilabel(app.UIFigure);
            app.LBYEC2BMedicalCenterLabel.Text = 'LBYEC2B Medical Center';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.PersonalInformationLabel = uilabel(app.UIFigure);
            app.PersonalInformationLabel.Text = 'Personal Information';
            app.NameField = uieditfield(app.UIFigure, 'text');
            app.EmailLabel_2 = uilabel(app.UIFigure);
            app.EmailLabel_2.Text = 'Name';
            app.EmailLabel_3 = uilabel(app.UIFigure);
            app.EmailLabel_3.Text = 'Address';
            app.AddressField = uieditfield(app.UIFigure, 'text');
            app.AgeField = uieditfield(app.UIFigure, 'numeric');
            app.EmailLabel_4 = uilabel(app.UIFigure);
            app.EmailLabel_4.Text = 'Age';
            app.BirthdayField = uidatepicker(app.UIFigure);
            app.EmailLabel_5 = uilabel(app.UIFigure);
            app.EmailLabel_5.Text = 'Date of Birth';
            app.GenderField = uidropdown(app.UIFigure);
            app.GenderField.Items = {'Male', 'Female', 'Intersex'};
            app.GenderField.Value = 'Male';
            app.ContactField = uieditfield(app.UIFigure, 'numeric');
            app.ContactField.ValueDisplayFormat = '%0.0d';
            app.EmailLabel_6 = uilabel(app.UIFigure);
            app.EmailLabel_6.Text = 'Sex';
            app.EmailLabel_7 = uilabel(app.UIFigure);
            app.EmailLabel_7.Text = 'Contact No.';
            app.LoginInformationLabel = uilabel(app.UIFigure);
            app.LoginInformationLabel.Text = 'Login Information';
            app.EmailField = uieditfield(app.UIFigure, 'text');
            app.EmailLabel_8 = uilabel(app.UIFigure);
            app.EmailLabel_8.Text = 'Email';
            app.EmailLabel_9 = uilabel(app.UIFigure);
            app.EmailLabel_9.Text = 'Password';
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.EmailLabel_10 = uilabel(app.UIFigure);
            app.EmailLabel_10.Text = 'Confirm Password';
            app.ConfirmPasswordField = uieditfield(app.UIFigure, 'text');
            app.IagreetothetermsandconditionsofthisprogramCheckBox = uicheckbox(app.UIFigure);
            app.IagreetothetermsandconditionsofthisprogramCheckBox.Text = 'I agree to the terms and conditions of this program.';
            app.CreateAccountButton = uibutton(app.UIFigure, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Text = 'Create Account';
            app.BacktoSignInButton = uibutton(app.UIFigure, 'push');
            app.BacktoSignInButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoSignInButtonPushed, true);
            app.BacktoSignInButton.Text = 'Back to Sign In';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateAccountPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end