classdef DashboardPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DoctorsTab                  matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        Panel_8                     matlab.ui.container.Panel
        GridLayout2_8               matlab.ui.container.GridLayout
        ScheduleConsultNowButton_8  matlab.ui.control.Button
        GridLayout3_8               matlab.ui.container.GridLayout
        DatesAvailableLabel_8       matlab.ui.control.Label
        RoomNumberLabel_8           matlab.ui.control.Label
        SpecializationLabel_8       matlab.ui.control.Label
        DoctorNameLabel_8           matlab.ui.control.Label
        Image_8                     matlab.ui.control.Image
        Panel_7                     matlab.ui.container.Panel
        GridLayout2_7               matlab.ui.container.GridLayout
        ScheduleConsultNowButton_7  matlab.ui.control.Button
        GridLayout3_7               matlab.ui.container.GridLayout
        DatesAvailableLabel_7       matlab.ui.control.Label
        RoomNumberLabel_7           matlab.ui.control.Label
        SpecializationLabel_7       matlab.ui.control.Label
        DoctorNameLabel_7           matlab.ui.control.Label
        Image_7                     matlab.ui.control.Image
        Panel_6                     matlab.ui.container.Panel
        GridLayout2_6               matlab.ui.container.GridLayout
        ScheduleConsultNowButton_6  matlab.ui.control.Button
        GridLayout3_6               matlab.ui.container.GridLayout
        DatesAvailableLabel_6       matlab.ui.control.Label
        RoomNumberLabel_6           matlab.ui.control.Label
        SpecializationLabel_6       matlab.ui.control.Label
        DoctorNameLabel_6           matlab.ui.control.Label
        Image_6                     matlab.ui.control.Image
        Panel_5                     matlab.ui.container.Panel
        GridLayout2_5               matlab.ui.container.GridLayout
        ScheduleConsultNowButton_5  matlab.ui.control.Button
        GridLayout3_5               matlab.ui.container.GridLayout
        DatesAvailableLabel_5       matlab.ui.control.Label
        RoomNumberLabel_5           matlab.ui.control.Label
        SpecializationLabel_5       matlab.ui.control.Label
        DoctorNameLabel_5           matlab.ui.control.Label
        Image_5                     matlab.ui.control.Image
        Panel_4                     matlab.ui.container.Panel
        GridLayout2_4               matlab.ui.container.GridLayout
        ScheduleConsultNowButton_4  matlab.ui.control.Button
        GridLayout3_4               matlab.ui.container.GridLayout
        DatesAvailableLabel_4       matlab.ui.control.Label
        RoomNumberLabel_4           matlab.ui.control.Label
        SpecializationLabel_4       matlab.ui.control.Label
        DoctorNameLabel_4           matlab.ui.control.Label
        Image_4                     matlab.ui.control.Image
        Panel_3                     matlab.ui.container.Panel
        GridLayout2_3               matlab.ui.container.GridLayout
        ScheduleConsultNowButton_3  matlab.ui.control.Button
        GridLayout3_3               matlab.ui.container.GridLayout
        DatesAvailableLabel_3       matlab.ui.control.Label
        RoomNumberLabel_3           matlab.ui.control.Label
        SpecializationLabel_3       matlab.ui.control.Label
        DoctorNameLabel_3           matlab.ui.control.Label
        Image_3                     matlab.ui.control.Image
        Panel_2                     matlab.ui.container.Panel
        GridLayout2_2               matlab.ui.container.GridLayout
        ScheduleConsultNowButton2   matlab.ui.control.Button
        GridLayout3_2               matlab.ui.container.GridLayout
        DatesAvailableLabel_2       matlab.ui.control.Label
        RoomNumberLabel_2           matlab.ui.control.Label
        SpecializationLabel_2       matlab.ui.control.Label
        DoctorNameLabel_2           matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ScheduleConsultNowButton1   matlab.ui.control.Button
        GridLayout3                 matlab.ui.container.GridLayout
        DatesAvailableLabel         matlab.ui.control.Label
        RoomNumberLabel             matlab.ui.control.Label
        SpecializationLabel         matlab.ui.control.Label
        DoctorNameLabel             matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ViewAppointmentsMadeTab     matlab.ui.container.Tab
        AppointmentTable            matlab.ui.control.Table
    end

    
    properties (Access = public)
        DoctorData % For initializing the struct
        CurrentUser
    end
    
    methods (Access = private)
        
        function updateDoctorCard(app, index, nameLabel, specLabel, roomLabel, schedLabel, imageComponent)
            
            doc = app.DoctorData(index);
            nameLabel.Text = doc.name;
            specLabel.Text = doc.specialization;
            roomLabel.Text = doc.room;
            schedLabel.Text = doc.schedule;
            if isfile(doc.image)
                imageComponent.ImageSource = doc.image;
            else
                imageComponent.ImageSource = 'default.png';
            end
        end
    
        function loadUserAppointments(app)
        
        fileName = 'appointmentsData.mat';

      
        if isfile(fileName)
            load(fileName, 'appointmentsData');
        else
            appointmentsData = [];
        end

    
        if ~isempty(appointmentsData)
            userAppointments = appointmentsData( ...
            strcmpi(strtrim({appointmentsData.Creator}), strtrim(app.CurrentUser.name)));
        else
            userAppointments = [];
        end

        
        if ~isempty(userAppointments)
            tableData = cell(length(userAppointments), 6);
            for i = 1 : length(userAppointments)
                tableData{i,1} = userAppointments(i).Doctor;
                tableData{i,2} = userAppointments(i).Room;
                tableData{i,3} = datestr(userAppointments(i).Date, 'yyyy-mm-dd');
                tableData{i,4} = userAppointments(i).Time;
                tableData{i,5} = userAppointments(i).Type;
                tableData{i,6} = userAppointments(i).Patient;
            end
        else
            tableData = {};
        end

        % Set UITable data
        app.AppointmentTable.Data = tableData;
    end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

      
            % Initialize doctor data
            app.DoctorData = struct( ...
                'name', {'Dr. Jose Rizal', 'Dr. John Doe', 'Dr. Maria Luna', 'Dr. Daniel Cruz', ...
                         'Dr. Elena Santos', 'Dr. Miguel Ramos', 'Dr. Carla Reyes', 'Dr. Alvin Uy'}, ...
                'specialization', {'General Surgery', 'Spine Surgery', 'Pediatrics', 'Cardiology', ...
                                   'Neurology', 'Orthopedics', 'ENT', 'Internal Medicine'}, ...
                'room', {'Room 101', 'Room 203', 'Room 305', 'Room 402', ...
                         'Room 105', 'Room 206', 'Room 308', 'Room 409'}, ...
                'schedule', {'Mon, Wed, Fri', 'Tue, Thu', 'Mon to Sat', 'Mon, Fri', ...
                             'Wed only', 'Tue, Thu', 'Mon-Wed', 'Daily'}, ...
                'image', {'rizal.png', 'doe.png', 'luna.png', 'cruz.png', ...
                          'santos.png', 'ramos.png', 'reyes.png', 'uy.png'} ...
            );


            % Group UI components into arrays for looping
            nameLabels = [app.DoctorNameLabel, app.DoctorNameLabel_2, app.DoctorNameLabel_3, app.DoctorNameLabel_4, ...
                          app.DoctorNameLabel_5, app.DoctorNameLabel_6, app.DoctorNameLabel_7, app.DoctorNameLabel_8];
        
            specLabels = [app.SpecializationLabel, app.SpecializationLabel_2, app.SpecializationLabel_3, app.SpecializationLabel_4, ...
                          app.SpecializationLabel_5, app.SpecializationLabel_6, app.SpecializationLabel_7, app.SpecializationLabel_8];
        
            roomLabels = [app.RoomNumberLabel, app.RoomNumberLabel_2, app.RoomNumberLabel_3, app.RoomNumberLabel_4, ...
                          app.RoomNumberLabel_5, app.RoomNumberLabel_6, app.RoomNumberLabel_7, app.RoomNumberLabel_8];
        
            schedLabels = [app.DatesAvailableLabel, app.DatesAvailableLabel_2, app.DatesAvailableLabel_3, app.DatesAvailableLabel_4, ...
                           app.DatesAvailableLabel_5, app.DatesAvailableLabel_6, app.DatesAvailableLabel_7, app.DatesAvailableLabel_8];
        
            images = [app.Image, app.Image_2, app.Image_3, app.Image_4, ...
                      app.Image_5, app.Image_6, app.Image_7, app.Image_8];
            % Populate doctor cards in a loop
            for i = 1:numel(app.DoctorData)
                updateDoctorCard(app, i, nameLabels(i), specLabels(i), roomLabels(i), schedLabels(i), images(i));
            end

            if isfile('currentUser.mat') && isfile('userAccounts.mat')
                load('currentUser.mat', 'idx');
                load('userAccounts.mat', 'users');
                if idx > 0 && idx <= length(users)
                    app.CurrentUser = users(idx);
                    disp(['Logged in as: ', app.CurrentUser.email])
                else
                    uialert(app.UIFigure, 'Invalid current user index.', 'Error');
                end
            else
                uialert(app.UIFigure, 'No logged-in user found. Please log in first.', 'Error');
            end


        end

        % Button pushed function: ScheduleConsultNowButton1
        function ScheduleConsultNowButton_1Pushed(app, event)
            AppointmentPage(app.DoctorNameLabel.Text, app.SpecializationLabel.Text, app.RoomNumberLabel.Text, app.DatesAvailableLabel.Text, app.Image.ImageSource)
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton2
        function ScheduleConsultNowButton_2Pushed(app, event)
            AppointmentPage(app.DoctorNameLabel_2.Text, app.SpecializationLabel_2.Text, app.RoomNumberLabel_2.Text, app.DatesAvailableLabel_2.Text, app.Image_2.ImageSource);
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton_3
        function ScheduleConsultNowButton_3Pushed(app, event)
          AppointmentPage(app.DoctorNameLabel_3.Text, app.SpecializationLabel_3.Text, app.RoomNumberLabel_3.Text, app.DatesAvailableLabel_3.Text, app.Image_3.ImageSource);
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton_4
        function ScheduleConsultNowButton_4Pushed(app, event)
          AppointmentPage(app.DoctorNameLabel_4.Text, app.SpecializationLabel_4.Text, app.RoomNumberLabel_4.Text, app.DatesAvailableLabel_4.Text, app.Image_4.ImageSource);
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton_5
        function ScheduleConsultNowButton_5Pushed(app, event)
          AppointmentPage(app.DoctorNameLabel_5.Text, app.SpecializationLabel_5.Text, app.RoomNumberLabel_5.Text, app.DatesAvailableLabel_5.Text, app.Image_5.ImageSource);
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton_6
        function ScheduleConsultNowButton_6Pushed(app, event)
          AppointmentPage(app.DoctorNameLabel_6.Text, app.SpecializationLabel_6.Text, app.RoomNumberLabel_6.Text, app.DatesAvailableLabel_6.Text, app.Image_6.ImageSource);
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton_7
        function ScheduleConsultNowButton_7Pushed(app, event)
          AppointmentPage(app.DoctorNameLabel_7.Text, app.SpecializationLabel_7.Text, app.RoomNumberLabel_7.Text, app.DatesAvailableLabel_7.Text, app.Image_7.ImageSource);
          delete(app);
        end

        % Button pushed function: ScheduleConsultNowButton_8
        function ScheduleConsultNowButton_8Pushed(app, event)
            AppointmentPage(app.DoctorNameLabel_8.Text, app.SpecializationLabel_8.Text, app.RoomNumberLabel_8.Text, app.DatesAvailableLabel_8.Text, app.Image_8.ImageSource);
          delete(app);
        end

        % Button down function: ViewAppointmentsMadeTab
        function ViewAppointmentsMadeTabButtonDown(app, event)
            loadUserAppointments(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DoctorsTab = uitab(app.TabGroup);
            app.DoctorsTab.Title = 'Doctors';
            app.GridLayout = uigridlayout(app.DoctorsTab);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Image = uiimage(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.DoctorNameLabel = uilabel(app.GridLayout3);
            app.DoctorNameLabel.Text = 'Doctor Name';
            app.SpecializationLabel = uilabel(app.GridLayout3);
            app.SpecializationLabel.Text = 'Specialization';
            app.RoomNumberLabel = uilabel(app.GridLayout3);
            app.RoomNumberLabel.Text = 'Room Number';
            app.DatesAvailableLabel = uilabel(app.GridLayout3);
            app.DatesAvailableLabel.Text = 'Dates Available';
            app.ScheduleConsultNowButton1 = uibutton(app.GridLayout2, 'push');
            app.ScheduleConsultNowButton1.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_1Pushed, true);
            app.ScheduleConsultNowButton1.Text = 'Schedule Consult Now';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel_2);
            app.Image_2 = uiimage(app.GridLayout2_2);
            app.GridLayout3_2 = uigridlayout(app.GridLayout2_2);
            app.DoctorNameLabel_2 = uilabel(app.GridLayout3_2);
            app.DoctorNameLabel_2.Text = 'Doctor Name';
            app.SpecializationLabel_2 = uilabel(app.GridLayout3_2);
            app.SpecializationLabel_2.Text = 'Specialization';
            app.RoomNumberLabel_2 = uilabel(app.GridLayout3_2);
            app.RoomNumberLabel_2.Text = 'Room Number';
            app.DatesAvailableLabel_2 = uilabel(app.GridLayout3_2);
            app.DatesAvailableLabel_2.Text = 'Dates Available';
            app.ScheduleConsultNowButton2 = uibutton(app.GridLayout2_2, 'push');
            app.ScheduleConsultNowButton2.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_2Pushed, true);
            app.ScheduleConsultNowButton2.Text = 'Schedule Consult Now';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2_3 = uigridlayout(app.Panel_3);
            app.Image_3 = uiimage(app.GridLayout2_3);
            app.GridLayout3_3 = uigridlayout(app.GridLayout2_3);
            app.DoctorNameLabel_3 = uilabel(app.GridLayout3_3);
            app.DoctorNameLabel_3.Text = 'Doctor Name';
            app.SpecializationLabel_3 = uilabel(app.GridLayout3_3);
            app.SpecializationLabel_3.Text = 'Specialization';
            app.RoomNumberLabel_3 = uilabel(app.GridLayout3_3);
            app.RoomNumberLabel_3.Text = 'Room Number';
            app.DatesAvailableLabel_3 = uilabel(app.GridLayout3_3);
            app.DatesAvailableLabel_3.Text = 'Dates Available';
            app.ScheduleConsultNowButton_3 = uibutton(app.GridLayout2_3, 'push');
            app.ScheduleConsultNowButton_3.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_3Pushed, true);
            app.ScheduleConsultNowButton_3.Text = 'Schedule Consult Now';
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout2_4 = uigridlayout(app.Panel_4);
            app.Image_4 = uiimage(app.GridLayout2_4);
            app.GridLayout3_4 = uigridlayout(app.GridLayout2_4);
            app.DoctorNameLabel_4 = uilabel(app.GridLayout3_4);
            app.DoctorNameLabel_4.Text = 'Doctor Name';
            app.SpecializationLabel_4 = uilabel(app.GridLayout3_4);
            app.SpecializationLabel_4.Text = 'Specialization';
            app.RoomNumberLabel_4 = uilabel(app.GridLayout3_4);
            app.RoomNumberLabel_4.Text = 'Room Number';
            app.DatesAvailableLabel_4 = uilabel(app.GridLayout3_4);
            app.DatesAvailableLabel_4.Text = 'Dates Available';
            app.ScheduleConsultNowButton_4 = uibutton(app.GridLayout2_4, 'push');
            app.ScheduleConsultNowButton_4.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_4Pushed, true);
            app.ScheduleConsultNowButton_4.Text = 'Schedule Consult Now';
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout2_5 = uigridlayout(app.Panel_5);
            app.Image_5 = uiimage(app.GridLayout2_5);
            app.GridLayout3_5 = uigridlayout(app.GridLayout2_5);
            app.DoctorNameLabel_5 = uilabel(app.GridLayout3_5);
            app.DoctorNameLabel_5.Text = 'Doctor Name';
            app.SpecializationLabel_5 = uilabel(app.GridLayout3_5);
            app.SpecializationLabel_5.Text = 'Specialization';
            app.RoomNumberLabel_5 = uilabel(app.GridLayout3_5);
            app.RoomNumberLabel_5.Text = 'Room Number';
            app.DatesAvailableLabel_5 = uilabel(app.GridLayout3_5);
            app.DatesAvailableLabel_5.Text = 'Dates Available';
            app.ScheduleConsultNowButton_5 = uibutton(app.GridLayout2_5, 'push');
            app.ScheduleConsultNowButton_5.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_5Pushed, true);
            app.ScheduleConsultNowButton_5.Text = 'Schedule Consult Now';
            app.Panel_6 = uipanel(app.GridLayout);
            app.GridLayout2_6 = uigridlayout(app.Panel_6);
            app.Image_6 = uiimage(app.GridLayout2_6);
            app.GridLayout3_6 = uigridlayout(app.GridLayout2_6);
            app.DoctorNameLabel_6 = uilabel(app.GridLayout3_6);
            app.DoctorNameLabel_6.Text = 'Doctor Name';
            app.SpecializationLabel_6 = uilabel(app.GridLayout3_6);
            app.SpecializationLabel_6.Text = 'Specialization';
            app.RoomNumberLabel_6 = uilabel(app.GridLayout3_6);
            app.RoomNumberLabel_6.Text = 'Room Number';
            app.DatesAvailableLabel_6 = uilabel(app.GridLayout3_6);
            app.DatesAvailableLabel_6.Text = 'Dates Available';
            app.ScheduleConsultNowButton_6 = uibutton(app.GridLayout2_6, 'push');
            app.ScheduleConsultNowButton_6.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_6Pushed, true);
            app.ScheduleConsultNowButton_6.Text = 'Schedule Consult Now';
            app.Panel_7 = uipanel(app.GridLayout);
            app.GridLayout2_7 = uigridlayout(app.Panel_7);
            app.Image_7 = uiimage(app.GridLayout2_7);
            app.GridLayout3_7 = uigridlayout(app.GridLayout2_7);
            app.DoctorNameLabel_7 = uilabel(app.GridLayout3_7);
            app.DoctorNameLabel_7.Text = 'Doctor Name';
            app.SpecializationLabel_7 = uilabel(app.GridLayout3_7);
            app.SpecializationLabel_7.Text = 'Specialization';
            app.RoomNumberLabel_7 = uilabel(app.GridLayout3_7);
            app.RoomNumberLabel_7.Text = 'Room Number';
            app.DatesAvailableLabel_7 = uilabel(app.GridLayout3_7);
            app.DatesAvailableLabel_7.Text = 'Dates Available';
            app.ScheduleConsultNowButton_7 = uibutton(app.GridLayout2_7, 'push');
            app.ScheduleConsultNowButton_7.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_7Pushed, true);
            app.ScheduleConsultNowButton_7.Text = 'Schedule Consult Now';
            app.Panel_8 = uipanel(app.GridLayout);
            app.GridLayout2_8 = uigridlayout(app.Panel_8);
            app.Image_8 = uiimage(app.GridLayout2_8);
            app.GridLayout3_8 = uigridlayout(app.GridLayout2_8);
            app.DoctorNameLabel_8 = uilabel(app.GridLayout3_8);
            app.DoctorNameLabel_8.Text = 'Doctor Name';
            app.SpecializationLabel_8 = uilabel(app.GridLayout3_8);
            app.SpecializationLabel_8.Text = 'Specialization';
            app.RoomNumberLabel_8 = uilabel(app.GridLayout3_8);
            app.RoomNumberLabel_8.Text = 'Room Number';
            app.DatesAvailableLabel_8 = uilabel(app.GridLayout3_8);
            app.DatesAvailableLabel_8.Text = 'Dates Available';
            app.ScheduleConsultNowButton_8 = uibutton(app.GridLayout2_8, 'push');
            app.ScheduleConsultNowButton_8.ButtonPushedFcn = createCallbackFcn(app, @ScheduleConsultNowButton_8Pushed, true);
            app.ScheduleConsultNowButton_8.Text = 'Schedule Consult Now';
            app.ViewAppointmentsMadeTab = uitab(app.TabGroup);
            app.ViewAppointmentsMadeTab.Title = 'View Appointments Made';
            app.ViewAppointmentsMadeTab.ButtonDownFcn = createCallbackFcn(app, @ViewAppointmentsMadeTabButtonDown, true);
            app.AppointmentTable = uitable(app.ViewAppointmentsMadeTab);
            app.AppointmentTable.ColumnName = {'Doctor'; 'Room'; 'Date'; 'Time'; 'Booking Type'; 'Patient'};
            app.AppointmentTable.RowName = {};
            app.AppointmentTable.ColumnEditable = [false false false false false];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DashboardPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end