classdef LoginPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CreateAccountButton        matlab.ui.control.Button
        LoginButton                matlab.ui.control.Button
        PasswordField              matlab.ui.control.EditField
        EmailLabel_2               matlab.ui.control.Label
        EmailLabel                 matlab.ui.control.Label
        EmailField                 matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
        LBYEC2BMedicalCenterLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            enteredEmail = app.EmailField.Value;
            enteredPW = app.PasswordField.Value;

            if isempty(enteredEmail) || isempty(enteredPW) 
                uialert(app.UIFigure, 'Please enter both email and password.', 'Error')
                return;
            end

            
            if isfile('userAccounts.mat')
                load('userAccounts.mat', 'users');
                isValid = false;
                idx = -1;
                for i = 1:length(users)
                    if strcmp(users(i).email, enteredEmail) && strcmp(users(i).password, enteredPW)
                        isValid = true;
                        idx = i;
                        break;
                    end
                end
                
                if isValid
                    uialert(app.UIFigure, 'Login Successful!', 'Success');
                    save('currentUser.mat', "idx")
                    DashboardPage;
                    delete(app);
                else
                    uialert(app.UIFigure, 'Invalid email or password.', 'Error');
                end
            else
                uialert(app.UIFigure, 'No accounts registered yet. Please create an account first.', 'Error');
            end
        end

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
            CreateAccountPage;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LBYEC2BMedicalCenterLabel = uilabel(app.UIFigure);
            app.LBYEC2BMedicalCenterLabel.Text = 'LBYEC2B Medical Center';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.EmailField = uieditfield(app.UIFigure, 'text');
            app.EmailLabel = uilabel(app.UIFigure);
            app.EmailLabel.Text = 'Email';
            app.EmailLabel_2 = uilabel(app.UIFigure);
            app.EmailLabel_2.Text = 'Password';
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.CreateAccountButton = uibutton(app.UIFigure, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Text = 'Create Account';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end